classdef Brightness_Control_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HueValueTextArea              matlab.ui.control.TextArea
        HueValueTextAreaLabel         matlab.ui.control.Label
        HueControlSlider              matlab.ui.control.Slider
        HueControlSliderLabel         matlab.ui.control.Label
        BrightnessControlSlider       matlab.ui.control.Slider
        BrightnessControlSliderLabel  matlab.ui.control.Label
        BrightnessValueTextArea       matlab.ui.control.TextArea
        BrightnessValueTextAreaLabel  matlab.ui.control.Label
        LoadImageButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            img = imread('soul.jpeg');

            subplot(2,2,1);
            imshow(img)
            title('Original image')

            subplot(2,2,3);
            imshow(img)
            title('ADD equal value to each color channel')

            subplot(2,2,4);
            imshow(img)
            title('brightness control in HSV space')

            app.BrightnessControlSlider.Value = 50;
            
            app.BrightnessValueTextArea.Value = sprintf('%.6f',app.BrightnessControlSlider.Value);
        end

        % Value changed function: BrightnessControlSlider
        function BrightnessControlSliderValueChanged(app, event)
            value = app.BrightnessControlSlider.Value;
            app.BrightnessValueTextArea.Value = sprintf('%.6f',value); % value;

            img = imread('soul.jpeg');
            imgGrayUpdate = double(img) + (value-50);
            imgGrayUpdate(imgGrayUpdate < 0) = 0;
            imgGrayUpdate(imgGrayUpdate > 255) = 255;
            %figure(1), imshow(uint8(appleGrayUpdate))

            %HSV model
            i = double((value-50))/255.0;
            imgHSV = rgb2hsv(img);
            bright = imgHSV(:, :, 3) + i;
            bright(bright > 1) = 1;
            bright(bright < 0) = 0;
            imgHSV(:, :, 3) = bright;
            imgRGB = hsv2rgb(imgHSV);

            subplot(2,2,1);
            imshow(img)
            title('Original image')

            subplot(2,2,3);
            imshow(uint8(imgGrayUpdate))
            title('ADD equal value to each color channel')

            subplot(2,2,4);
            imshow(imgRGB)
            title('brightness control in HSV space')

        end

        % Value changed function: HueControlSlider
        function HueControlSliderValueChanged(app, event)
            value = app.HueControlSlider.Value;
            app.HueValueTextArea.Value = sprintf('%f', value); % value;

            img = imread('soul.jpeg');
            imgHueUpdate = double(img) + value;

            imgHSV = rgb2hsv(img);
            hue = imgHSV(:, :, 1) + value;
            hue = mod(hue, 1);
            imgHSV(:, :, 1) = hue;
            imgRGB = hsv2rgb(imgHSV);

            subplot(2,2,2);
            imshow(imgRGB)
            title('hue control in HSV space')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.BrightnessValueTextAreaLabel = uilabel(app.UIFigure);
            app.BrightnessValueTextAreaLabel.Text = 'Brightness Value';
            app.BrightnessValueTextArea = uitextarea(app.UIFigure);
            app.BrightnessControlSliderLabel = uilabel(app.UIFigure);
            app.BrightnessControlSliderLabel.Text = 'Brightness Control';
            app.BrightnessControlSlider = uislider(app.UIFigure);
            app.BrightnessControlSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessControlSliderValueChanged, true);
            app.HueControlSliderLabel = uilabel(app.UIFigure);
            app.HueControlSliderLabel.Text = 'Hue Control';
            app.HueControlSlider = uislider(app.UIFigure);
            app.HueControlSlider.ValueChangedFcn = createCallbackFcn(app, @HueControlSliderValueChanged, true);
            app.HueValueTextAreaLabel = uilabel(app.UIFigure);
            app.HueValueTextAreaLabel.Text = 'Hue Value';
            app.HueValueTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Brightness_Control_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end